classdef BarristaBotsGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                  matlab.ui.Figure
        ESTOPButton              matlab.ui.control.StateButton
        resumeButton             matlab.ui.control.Button
        movepumaButton           matlab.ui.control.Button
        LoadPuma560Button        matlab.ui.control.Button
        ExitButton               matlab.ui.control.Button
        LoadEnvironmentButton    matlab.ui.control.Button
        LoadOmronTM5Button       matlab.ui.control.Button
        LoadDobotMagicianButton  matlab.ui.control.Button
        plot                     matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        % place your class properties here
        modelDobot
        modelTM5
        modelPuma
        qz = [0     0     0     0     0     0];
        qrDobot = [0 pi/4 pi/4 0 0];
        qrTM5 = [0 0 0 0 0 0];
        qrPuma = [0	pi/2  -pi/2	0	0	0];
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function init(app, varargin)
            % Code to run once GUI is loaded successfully
            ax = gca;

            workspace = [-2 2 -2 2 -0.5 2];
            view(3)
            % Delete the green floor surface (need to count backwards
            % because once it is deleted the array is compressed)
            for i = size(ax.Children,1):-1:1
                if strcmp(ax.Children(i).Type,'surface')
                    delete(ax.Children(i));
                end
            end
            
            % Turn off this load button and enable the move button
            app.LoadDobotMagicianButton.Enable = true;
            app.LoadOmronTM5Button.Enable = true;
            
        end

        % Button pushed function: LoadDobotMagicianButton
        function LoadDobotMagicianButtonPushed(app, event)
            DobotMagician
            app.modelDobot = DobotMagician;
            app.modelDobot.model.base = transl([1,0,0]);
            %app.modelDobot.model.animate(app.qrDobot);
            drawnow();
           
            %plot(app.modelDobot,app.qrDobot,'workspace',[-2 2 -2 2 -0.5 2])
            hold on;
        end

        % Button pushed function: LoadOmronTM5Button
        function LoadOmronTM5ButtonPushed(app, event)
            OmronTM5
            app.modelTM5 = OmronTM5;
            app.modelTM5.model.base = transl([-1,0,0]);
            %app.modelTM5.model.animate(app.qrTM5);
            drawnow();
            hold on;
        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            delete(app)
        end

        % Button pushed function: LoadEnvironmentButton
        function LoadEnvironmentButtonPushed(app, event)
            
            
        end

        % Button pushed function: LoadPuma560Button
        function LoadPuma560ButtonPushed(app, event)
            mdl_puma560
            app.modelPuma = p560;
            app.modelPuma.base = transl([0.5,0,0]);
            plot(app.modelPuma,app.qrPuma,'workspace',[-2 2 -2 2 -0.5 2])            
            hold on; 
        end

        % Button pushed function: movepumaButton
        function movepumaButtonPushed(app, event)
            qmatrix = jtraj(app.qrPuma,app.qz,20);          
            for robotStepIndex = 1:size(qmatrix,1)

                app.modelPuma.animate(qmatrix(robotStepIndex,:));
                drawnow;
                
            end
        end

        % Button pushed function: resumeButton
        function resumeButtonPushed(app, event)
            qmatrix = jtraj(app.modelPuma.getpos,app.qz,20);          
            for robotStepIndex = 1:size(qmatrix,1)

                app.modelPuma.animate(qmatrix(robotStepIndex,:));
                drawnow;
            end
        end

        % Value changed function: ESTOPButton
        function ESTOPButtonValueChanged(app, event)
            value = app.ESTOPButton.Value;
%             app.modelDobot.animate(app.modelDobot.getpos);
%             app.modelTM5.animate(app.modelTM5.getpos);

%              app.modelPuma.animate(app.modelPuma.getpos);

            if app.ESTOPButton.Value
                  app.resumeButton.Enable = 'off';
                  app.ESTOPButton.Text = 'Stopped';
                  app.movepumaButton.Enable = 'off';
                  flag = true;
            else
                  app.resumeButton.Enable = 'on';
                  app.ESTOPButton.Text = 'E-STOP';
                  app.movepumaButton.Enable = 'on';
            end
            
            while value
                app.modelPuma.animate(app.modelPuma.getpos);
                pause(5)
            end

            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'BarristaBotsGUI';
            app.figure1.Tag = 'figure1';
            app.plot = uiaxes(app.figure1);
            xlabel(app.plot, 'X')
            ylabel(app.plot, 'Y')
            app.plot.Tag = 'axes1';
            app.LoadDobotMagicianButton = uibutton(app.figure1, 'push');
            app.LoadDobotMagicianButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDobotMagicianButtonPushed, true);
            app.LoadDobotMagicianButton.Text = 'Load Dobot Magician';
            app.LoadOmronTM5Button = uibutton(app.figure1, 'push');
            app.LoadOmronTM5Button.ButtonPushedFcn = createCallbackFcn(app, @LoadOmronTM5ButtonPushed, true);
            app.LoadOmronTM5Button.Text = 'Load Omron TM5';
            app.LoadEnvironmentButton = uibutton(app.figure1, 'push');
            app.LoadEnvironmentButton.ButtonPushedFcn = createCallbackFcn(app, @LoadEnvironmentButtonPushed, true);
            app.LoadEnvironmentButton.Text = 'Load Environment';
            app.ExitButton = uibutton(app.figure1, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
            app.LoadPuma560Button = uibutton(app.figure1, 'push');
            app.LoadPuma560Button.ButtonPushedFcn = createCallbackFcn(app, @LoadPuma560ButtonPushed, true);
            app.LoadPuma560Button.Text = 'Load Puma560';
            app.movepumaButton = uibutton(app.figure1, 'push');
            app.movepumaButton.ButtonPushedFcn = createCallbackFcn(app, @movepumaButtonPushed, true);
            app.movepumaButton.Text = 'move puma';
            app.resumeButton = uibutton(app.figure1, 'push');
            app.resumeButton.ButtonPushedFcn = createCallbackFcn(app, @resumeButtonPushed, true);
            app.resumeButton.Text = 'resume';
            app.ESTOPButton = uibutton(app.figure1, 'state');
            app.ESTOPButton.ValueChangedFcn = createCallbackFcn(app, @ESTOPButtonValueChanged, true);
            app.ESTOPButton.Text = 'E-STOP';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BarristaBotsGUI(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)init(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end